classdef BIDSconverter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        xASLpathEditField         matlab.ui.control.EditField
        xASLpathEditFieldLabel    matlab.ui.control.Label
        InfoTextArea              matlab.ui.control.TextArea
        InfoTextAreaLabel         matlab.ui.control.Label
        Convert2BIDSButton        matlab.ui.control.Button
        UITable2                  matlab.ui.control.Table
        ExamIDEditField           matlab.ui.control.EditField
        ExamIDEditFieldLabel      matlab.ui.control.Label
        UITable                   matlab.ui.control.Table
        PathtoBidsEditField       matlab.ui.control.EditField
        PathtoBidsEditFieldLabel  matlab.ui.control.Label
        SubjectsListBox           matlab.ui.control.ListBox
        SubjectsListBoxLabel      matlab.ui.control.Label
    end

    properties (Access = private)
        mainLoc=pwd;
        bidsdir % Root directory to bids data
        xASLdir % path to xASL
        exam_id ='0' % Exam id of highlighted data
        parTable %Participant table with subnum, exam_ID, srcname
        srcMapTable % source Table with already processed folders, the BIDS type ID, and the saved name tag (
        srcFold % The long list of folder names from the srcdata dir with adjacent ID filled in read from srcTable
        parLoc
        clickidx =1; %The index for folder choice when picking a subject directory
    end
    % methods (Access = public,Static)
    %     function [] = importASL(bidsdir,subnum,srcfilename,aslfile,xASLdir)
    %         addpath(fullfile(bidsdir,'code','ImportData'))
    %         % Full unpack including making the directories and removing them after
    %         %Write in the xASL import files
    %         cd(bidsdir)
    %         writeStudyPar(srcfilename)
    %         writeSourceStructure(srcfilename,aslfile)
    %         % Delete previous log and lock files
    %         % Delete log and lock files from xASL which may have logged that unpacking
    %         % has already been done
    %         try 
    %             rmdir(fullfile(bidsdir,'derivatives\ExploreASL\lock'), 's')
    %             rmdir(fullfile(bidsdir,'derivatives\ExploreASL\log'), 's')
    %         end
    %         % run xASL
    %         cd(xASLdir)
    %         ExploreASL(bidsdir,[1 1 0],0)
    %         % Move unpacked ASL into the proper subject folder then remove temp folder
    %         match = ["_","-"];
    %         unpckfoldname = erase(srcfilename,match); %Get compressed xASL name
    %         filename1=strcat(bidsdir,'\rawdata\sub-',unpckfoldname,'\perf\sub-',unpckfoldname,'_asl.json');
    %         filename2=strcat(bidsdir,'\rawdata\sub-',unpckfoldname,'\perf\sub-',unpckfoldname,'_asl.nii.gz');
    %         filename3=strcat(bidsdir,'\rawdata\sub-',unpckfoldname,'\perf\sub-',unpckfoldname,'_aslcontext.tsv');
    %         newfilename1=strcat(bidsdir,'\rawdata\sub-',num2str(subnum),'\perf\sub-',num2str(subnum),'_asl.json');
    %         newfilename2=strcat(bidsdir,'\rawdata\sub-',num2str(subnum),'\perf\sub-',num2str(subnum),'_asl.nii.gz');
    %         newfilename3=strcat(bidsdir,'\rawdata\sub-',num2str(subnum),'\perf\sub-',num2str(subnum),'_aslcontext.tsv');
    %         fileloc=strcat(bidsdir,'\rawdata\sub-',unpckfoldname,'\perf\');
    %         newfileloc=strcat(bidsdir,'\rawdata\sub-',num2str(subnum),'\perf\');
    %         try mkdir(newfileloc)
    %         end
    %         cd(fileloc)
    %         movefile(filename1,newfilename1)
    %         movefile(filename2,newfilename2)
    %         movefile(filename3,newfilename3)
    %         % remove directory, delete dataset description
    %         rmdir(strcat(bidsdir,'\rawdata\sub-',unpckfoldname), 's')
    %         delete(strcat(bidsdir,'\rawdata\dataset_description.json'))
    %         delete(strcat(bidsdir,'\studyPar.json'))
    %         delete(strcat(bidsdir,'\sourceStructure.json'))
    %         cd(bidsdir)
    %     end
    % end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PathtoBidsEditField
        function PathtoBidsEditFieldValueChanged(app, event)
            %% Sort subject selection into processed and not processed
            app.bidsdir = app.PathtoBidsEditField.Value;
            try cd(app.bidsdir)
                app.InfoTextArea.Value='Loaded directory.';
            catch
                app.InfoTextArea.Value='Warning, not a real directory.';
                return
            end
            try cd(strcat(app.bidsdir,'\rawdata'))
                app.parTable = readtable("participants.tsv", "FileType","text",'Delimiter', '\t');
            catch
                srcname=[];
                subnum=[];
                exam_ID=[];
                app.parTable = table(subnum,srcname,exam_ID);
            end
            named=app.parTable.subnum(:);
            AlreadyProcessed=app.parTable.srcname(:);
            
            try cd(strcat(app.bidsdir,'\sourcedata'))
                app.InfoTextArea.Value='source data exists';
            catch
                app.InfoTextArea.Value='Warning, sourcedata DNE';
                return
            end

            srcDIR=dir;
            srcDIR(1:2,:)=[];
            count=1;
            for i=1:length(srcDIR)
                In = sum(strcmpi(srcDIR(i).name,AlreadyProcessed));
                if In==0
                    foldername{count,1}=srcDIR(i).name;
                    count=count+1;
                end
            end
            if count==1
                foldername=[];
            end
            app.SubjectsListBox.Items=[named;foldername];
            %% Load BIDS map
            varNames={'ID','type','suff','ses','task','acq','rec','run'};
            [init_type{1:80,1}]=deal('');
            cnames = categorical(init_type,{'anat','flow','perf','func','fmap','moti'});
            varTypes={'string','categorical','string','string','string','string','string','string'};
            Table=table('Size',[80,length(varNames)],'VariableNames',varNames,'VariableTypes',varTypes);
            Table.type=cnames;
            if exist(strcat(app.bidsdir,'\rawdata\BIDSmap.tsv'))
                cd(strcat(app.bidsdir,'\rawdata\'))
                Table_init = readtable("BIDSmap.tsv", "FileType","text",'Delimiter', '\t');
                [row,col] = size(Table_init);
                Table(1:row,1:col)=Table_init;
            end
            app.UITable.Data=Table;
        end

        % Clicked callback: SubjectsListBox
        function SubjectsListBoxClicked(app, event)
            %% This will load a participant srcMap.tsv (into a Table) of any bids that have already been processed
            app.clickidx = event.InteractionInformation.Item;
            fname = app.SubjectsListBox.Items{app.clickidx}; %Clicked directory name
            isAlrdySorted = regexp(fname,'sub\-d*'); %Is this a bids name already?
            if sum(isAlrdySorted) == 1 %If so
                cd(strcat(app.bidsdir,'\rawdata\')) %Go to bids
                matches = strcmpi(fname,app.parTable.subnum(:)); % find name matches in participants table
                app.parLoc = find(matches==1); %get the index 
                app.ExamIDEditField.Value = string(app.parTable.exam_ID(app.parLoc)); %collect saved exam ID, STRING
                app.exam_id = app.ExamIDEditField.Value; %Internally save exam_id
                if exist(strcat(app.bidsdir,'\rawdata\',fname,'\','srcmap.tsv'))
                    cd(strcat(app.bidsdir,'\rawdata\',fname))
                    app.srcMapTable = readtable("srcmap.tsv", "FileType","text",'Delimiter', '\t');
                else
                    srcname = []; ID = []; savedname = [];
                    app.srcMapTable = table(srcname,ID,savedname);
                end
                cd(strcat(app.bidsdir,'\sourcedata\',string(app.parTable.srcname(app.parLoc))))
            else
                app.parLoc = 0;
                srcname = []; ID = []; savedname = [];
                app.srcMapTable = table(srcname,ID,savedname);
                app.ExamIDEditField.Value='0';
                cd(strcat(app.bidsdir,'\sourcedata\',fname))
            end
            %% At this point, we're in the raw data folder and may have srcTable to identify if any folders have already been loaded.
            srcDIR = dir;
            srcDIR(1:2,:) = [];
            [rows,~] = size(app.srcMapTable);
            for i = 1:length(srcDIR)
                checkname = srcDIR(i).name;
                if rows > 0
                    matches = strcmpi(checkname,app.srcMapTable.srcname(:));
                    if sum(matches) == 1
                        Loc = find(matches==1);
                        IDtag{i,1} = char(app.srcMapTable.ID(Loc)); %load the IDtag that was used to process Bids conversion saved in Bidsmap
                        FoldName{i,1} = checkname;
                    else
                        IDtag{i,1}='no ID';
                        FoldName{i,1} = checkname;
                    end
                else
                    IDtag{i,1}='no ID';
                    FoldName{i,1} = checkname;
                end
            end
            app.srcFold = table(FoldName,IDtag);
            app.UITable2.Data = app.srcFold; %Put the srcFolder into the app with folder names and tags loaded from srcMapTable
        end

        % Value changed function: ExamIDEditField
        function ExamIDEditFieldValueChanged(app, event)
            app.exam_id = app.ExamIDEditField.Value;
        end

        % Button pushed function: Convert2BIDSButton
        function Convert2BIDSButtonPushed(app, event)
            %% Step 1: Save BIDSmap
            try mkdir(fullfile(app.bidsdir,'rawdata'))
            end
            writetable(app.UITable.Data, fullfile(app.bidsdir,'rawdata\BIDSmap.tsv'), ...
              'FileType','text','Delimiter','\t');
            %% Step 2: Identify Subject and/or Add New Subject, save participant Table
            fname=app.SubjectsListBox.Items{app.clickidx};
            if app.parLoc==0 %this means it's a new subject
                [rows,~]=size(app.parTable);
                if rows>0 %this means there is already processed subjects
                    app.parTable.exam_ID=cellstr(string(app.parTable.exam_ID));
                    newRow=app.parTable(1,:);
                    newRow(1,:)={''};
                    newRow.srcname={fname};
                    num=num2str((rows+1),'%03d');
                    subName=strcat('sub-',num);
                    newRow.subnum=cellstr(subName);
                    newRow.exam_ID={app.exam_id};
                    newparTable=[app.parTable;newRow];
                    mkdir(fullfile(app.bidsdir,'rawdata',subName))
                else %this means it's the first subject
                    subName='sub-001';
                    srcname={fname}; subnum=cellstr(subName); exam_ID={str2double(app.ExamIDEditField.Value)};
                    newparTable = table(subnum,srcname,exam_ID);
                    mkdir(fullfile(app.bidsdir,'rawdata',subName))
                end
                writetable(newparTable, fullfile(app.bidsdir,'rawdata\participants.tsv'), ...
                'FileType','text','Delimiter','\t'); %save the table
                app.SubjectsListBox.Items{app.clickidx} = subName; %update the items list name
                app.parTable = readtable(fullfile(app.bidsdir,'rawdata\participants.tsv'), "FileType","text",'Delimiter', '\t');
                srcName = fname; %and grab source folder name
                app.parLoc=1; 
                %% FOR SOME REASON THE PARTICIPANT SUBJECT NAME IS SAVED AS sub-001
            else %This is an existing subject
                app.parTable.exam_ID(app.parLoc) = str2double(app.ExamIDEditField.Value); %we can still update the exam ID
                writetable(app.parTable, fullfile(app.bidsdir,'rawdata\participants.tsv'), ...
                'FileType','text','Delimiter','\t'); %save table
                subName = string(app.parTable.subnum(app.parLoc)); %grab the subject number
                srcName = string(app.parTable.srcname(app.parLoc)); %and grab source folder name
            end
            %% Build Nifty Names and folder structures
            BidsTable=app.UITable.Data;
            SubTable=app.UITable2.Data;
            [h,~]=size(BidsTable);
            count=1;
            %cd('C:\Users\sdem348\Desktop\DTDS\code')
            %save('newTable.mat','BidsTable')
            BidNames={};
            for i=1:h
                try
                    if length(BidsTable.ID{i}) >0
                    %% Build Name
                    BidName=subName;
                    try
                        if (length(BidsTable.ses{i}) > 0)
                            BidName=strcat(BidName,'_ses-',BidsTable.ses{i});
                        end
                    end
                    try
                        if (length(BidsTable.task{i}) > 0)
                            BidName=strcat(BidName,'_task-',BidsTable.task{i});
                        end
                    end
                    try
                        if (length(BidsTable.acq{i}) > 0)
                            BidName=strcat(BidName,'_acq-',BidsTable.acq{i});
                        end
                    end
                    try
                        if (length(BidsTable.rec{i}) > 0)
                            BidName=strcat(BidName,'_rec-',BidsTable.rec{i});
                        end
                    end
                    try
                        if (length(BidsTable.run{i}) > 0)
                            BidName=strcat(BidName,'_run-',BidsTable.run{i});
                        end
                    end
                    try
                        if (length(BidsTable.suff{i}) > 0)
                            BidName=strcat(BidName,'_',BidsTable.suff{i});
                        end
                    end
                    BidNames{count,3}=BidName;
                    BidNames{count,2}={BidsTable.ID{i}};
                    BidNames{count,1}=i;
                    count=count+1;
                    end
                catch
                end
            end
            if sum(size(BidNames)) >0
                count=0;
                srcname = {}; ID = {}; savedname = {};
                for i=1:length(BidNames(:,1))
                    matches = strcmpi(BidNames{i,2},SubTable.IDtag(:));
                    Loc=find(matches==1);
                    if Loc >0
                        count=count+1;
                        %% Check if file exists
                        %Loc
                        N1=exist(strcat(app.bidsdir,'\rawdata\',subName,'\',string(BidsTable.type(BidNames{i,1})),'\',string(BidNames{i,3}),'.nii'));
                        N2=exist(strcat(app.bidsdir,'\rawdata\',subName,'\',string(BidsTable.type(BidNames{i,1})),'\',string(BidNames{i,3}),'.nii.gz'));
                        if N1+N2>0
                            fprintf(strcat(string(BidNames{i,3}),' already exists, skipping...\n'))
                        else
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            % There will be two options for unpacking, using dcm2nixx directly, or for ASL data, 
                            % running the xASL import which also uses dcm2niix but with an updated BIDS appropriate 
                            % json sidecar required to run xASL
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            %run all expected imports
                            %bidsdir=app.bidsdir
                            %xASLdir=app.xASLdir
                            %srcfilename=srcName
                            %aslfile=string(SubTable{Loc,1})
                            try NUM=subName(5:7);
                            catch
                                NUM=subName{1}(5:7);
                            end
                            if sum(strcmpi(BidNames{i,2},{'asl'}))>0
                                if length(app.xASLdir)>0
                                    fprintf('this was ASL data\n')
                                    cd(app.mainLoc)
                                    importASL(app.mainLoc,app.bidsdir,NUM,srcName,string(SubTable{Loc,1}),app.xASLdir)
                                    cd(app.bidsdir)
                                    else
                                        app.InfoTextArea.Value='Explore ASL path not input, asl not converted!';
                                end
                            else
                                path2dcm=strcat(app.bidsdir,'\sourcedata\',srcName,'\',string(SubTable{Loc,1}));
                                path2save=strcat(app.bidsdir,'\rawdata\',subName,'\',string(BidsTable.type(BidNames{i,1})));
                                try mkdir(path2save) 
                                end
                                BIDSname=BidNames{i,3};
                                cd(app.mainLoc)
                                convertopti=strcat('dcm2niix.exe',{' -f '},BIDSname,...
                                    {' -b y -ba y -z n -o "'},path2save,{'" "'},path2dcm,'');
                                system(convertopti{1});
                                cd(app.bidsdir)
                            end
                        end
                        srcname{count,1} = string(SubTable{Loc,1});
                        ID{count,1} = string(BidNames{i,2});
                        savedname{count,1} = string(BidNames{i,3});
                    end
                end
                new_srcMapTable = table(srcname,ID,savedname);
                writetable(new_srcMapTable, fullfile(app.bidsdir,'rawdata',subName,'srcmap.tsv'), ...
                    'FileType','text','Delimiter','\t');
            end
        end

        % Value changed function: xASLpathEditField
        function xASLpathEditFieldValueChanged(app, event)
            app.xASLdir = app.xASLpathEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SubjectsListBoxLabel = uilabel(app.UIFigure);
            app.SubjectsListBoxLabel.Text = 'Subjects';
            app.SubjectsListBox = uilistbox(app.UIFigure);
            app.SubjectsListBox.Items = {};
            app.SubjectsListBox.ClickedFcn = createCallbackFcn(app, @SubjectsListBoxClicked, true);
            app.SubjectsListBox.Value = {};
            app.PathtoBidsEditFieldLabel = uilabel(app.UIFigure);
            app.PathtoBidsEditFieldLabel.Text = 'Path to Bids';
            app.PathtoBidsEditField = uieditfield(app.UIFigure, 'text');
            app.PathtoBidsEditField.ValueChangedFcn = createCallbackFcn(app, @PathtoBidsEditFieldValueChanged, true);
            app.PathtoBidsEditField.Placeholder = 'path to bids project folder';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'ID'; 'type'; 'suff'; 'ses'; 'task'; 'acq'; 'rec'; 'run'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.Tag = 'SubjectTable';
            app.ExamIDEditFieldLabel = uilabel(app.UIFigure);
            app.ExamIDEditFieldLabel.Text = 'Exam ID';
            app.ExamIDEditField = uieditfield(app.UIFigure, 'text');
            app.ExamIDEditField.InputType = 'digits';
            app.ExamIDEditField.ValueChangedFcn = createCallbackFcn(app, @ExamIDEditFieldValueChanged, true);
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'rawScanName'; 'ID'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = true;
            app.Convert2BIDSButton = uibutton(app.UIFigure, 'push');
            app.Convert2BIDSButton.ButtonPushedFcn = createCallbackFcn(app, @Convert2BIDSButtonPushed, true);
            app.Convert2BIDSButton.Text = 'Convert2BIDS';
            app.InfoTextAreaLabel = uilabel(app.UIFigure);
            app.InfoTextAreaLabel.Text = 'Info';
            app.InfoTextArea = uitextarea(app.UIFigure);
            app.xASLpathEditFieldLabel = uilabel(app.UIFigure);
            app.xASLpathEditFieldLabel.Text = 'xASL path';
            app.xASLpathEditField = uieditfield(app.UIFigure, 'text');
            app.xASLpathEditField.ValueChangedFcn = createCallbackFcn(app, @xASLpathEditFieldValueChanged, true);
            app.xASLpathEditField.Placeholder = 'insrt path to xASL (optional for ASL data)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BIDSconverter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end